classdef retrospective < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RetrospectiveUIFigure        matlab.ui.Figure
        DashboardPanel               matlab.ui.container.Panel
        LoadMRDfileButton            matlab.ui.control.Button
        DetectedratesBPMPanel        matlab.ui.container.Panel
        respbpmLabel                 matlab.ui.control.Label
        RespirationEditField         matlab.ui.control.NumericEditField
        heartbpmLabel                matlab.ui.control.Label
        HeartEditField               matlab.ui.control.NumericEditField
        windowLabel                  matlab.ui.control.Label
        RespirationWindowEditField   matlab.ui.control.NumericEditField
        RespirationWidthEditField    matlab.ui.control.NumericEditField
        HeartWidthEditField          matlab.ui.control.NumericEditField
        centerLabel                  matlab.ui.control.Label
        widthLabel                   matlab.ui.control.Label
        ResetFilterValuesButton      matlab.ui.control.Button
        Lamp1                        matlab.ui.control.Lamp
        FilterNavigatorButton        matlab.ui.control.Button
        Lamp2                        matlab.ui.control.Lamp
        SortKspaceButton             matlab.ui.control.Button
        Lamp3                        matlab.ui.control.Lamp
        ReconstructButton            matlab.ui.control.Button
        Lamp4                        matlab.ui.control.Lamp
        CSrecoparametersPanel        matlab.ui.container.Panel
        TVtimeEditFieldLabel         matlab.ui.control.Label
        TVtimeEditField              matlab.ui.control.NumericEditField
        WVxyEditFieldLabel           matlab.ui.control.Label
        WVxyEditField                matlab.ui.control.NumericEditField
        RecoLabel                    matlab.ui.control.Label
        RecoEditField                matlab.ui.control.EditField
        ProgressGauge                matlab.ui.control.LinearGauge
        TVxyEditFieldLabel           matlab.ui.control.Label
        TVxyEditField                matlab.ui.control.NumericEditField
        VNSACheckBox                 matlab.ui.control.CheckBox
        VNSALabel                    matlab.ui.control.Label
        ExportDicomButton            matlab.ui.control.Button
        Lamp5                        matlab.ui.control.Lamp
        CinePanel                    matlab.ui.container.Panel
        framesEditFieldLabel         matlab.ui.control.Label
        FramesEditField              matlab.ui.control.NumericEditField
        movieDropDownLabel           matlab.ui.control.Label
        CardiacRespDropDown          matlab.ui.control.DropDown
        ExitButton                   matlab.ui.control.Button
        ExportGIFButton              matlab.ui.control.Button
        Lamp6                        matlab.ui.control.Lamp
        SelfgatedCardiacMRIReconstructorLabel  matlab.ui.control.Label
        AcquisitionparametersPanel   matlab.ui.container.Panel
        NyEditFieldLabel             matlab.ui.control.Label
        NyViewField                  matlab.ui.control.NumericEditField
        NxEditFieldLabel             matlab.ui.control.Label
        NxViewField                  matlab.ui.control.NumericEditField
        klinesrepetitionLabel        matlab.ui.control.Label
        KlinesRepetitionViewField    matlab.ui.control.NumericEditField
        repetitionsLabel             matlab.ui.control.Label
        RepetitionsViewField         matlab.ui.control.NumericEditField
        klinesLabel                  matlab.ui.control.Label
        NrKlinesViewField            matlab.ui.control.NumericEditField
        scantimeminLabel             matlab.ui.control.Label
        AcquisitionTimeMinViewField  matlab.ui.control.NumericEditField
        DataFile                     matlab.ui.control.TextArea
        datafileLabel                matlab.ui.control.Label
        averagesEditFieldLabel       matlab.ui.control.Label
        AveragesViewField            matlab.ui.control.NumericEditField
        fillingLabel                 matlab.ui.control.Label
        FillingViewField             matlab.ui.control.NumericEditField
        TRmsLabel                    matlab.ui.control.Label
        TRViewField                  matlab.ui.control.NumericEditField
        trajectoryEditFieldLabel     matlab.ui.control.Label
        TrajectoryViewField          matlab.ui.control.EditField
        TrajectoryFig                matlab.ui.control.UIAxes
        GustavStrijkersAmsterdamUMCVersion36Label  matlab.ui.control.Label
        NavigatorPanel               matlab.ui.container.Panel
        RawNavFig                    matlab.ui.control.UIAxes
        SpectrumFig                  matlab.ui.control.UIAxes
        primaryLabel                 matlab.ui.control.Label
        NavigatorEndEditField        matlab.ui.control.NumericEditField
        pointsLabel                  matlab.ui.control.Label
        NavigatorNRPointsEditField   matlab.ui.control.NumericEditField
        FlipSwitchLabel              matlab.ui.control.Label
        NavigatorFlipSwitch          matlab.ui.control.ToggleSwitch
        NavigatorLabel               matlab.ui.control.Label
        NavigatoranalysisPanel       matlab.ui.container.Panel
        CardiacNavFig                matlab.ui.control.UIAxes
        RespirationNavFig            matlab.ui.control.UIAxes
        HeartRateTimeFig             matlab.ui.control.UIAxes
        RespirationRateTimeFig       matlab.ui.control.UIAxes
        startEditFieldLabel          matlab.ui.control.Label
        StartEditField               matlab.ui.control.NumericEditField
        rangeEditFieldLabel          matlab.ui.control.Label
        RangeEditField               matlab.ui.control.NumericEditField
        DisplayrangeLabel            matlab.ui.control.Label
        StartSlider                  matlab.ui.control.Slider
        RangeSlider                  matlab.ui.control.Slider
        WindowCheckBox               matlab.ui.control.CheckBox
        startEditFieldLabel_2        matlab.ui.control.Label
        WindowStartEditField         matlab.ui.control.NumericEditField
        endLabel                     matlab.ui.control.Label
        WindowEndEditField           matlab.ui.control.NumericEditField
        WindowStartSlider            matlab.ui.control.Slider
        WindowEndSlider              matlab.ui.control.Slider
        MoviePanel                   matlab.ui.container.Panel
        PlayButton                   matlab.ui.control.Button
        StopButton                   matlab.ui.control.Button
        frameLabel                   matlab.ui.control.Label
        MovieFrameEditField          matlab.ui.control.NumericEditField
        intensityscalingLabel        matlab.ui.control.Label
        MovieScaleSlider             matlab.ui.control.Slider
        ccButton                     matlab.ui.control.Button
        ccwButton                    matlab.ui.control.Button
        RotateLabel                  matlab.ui.control.Label
        FrameIncrButton              matlab.ui.control.Button
        FrameLabel                   matlab.ui.control.Label
        FrameDecrButton              matlab.ui.control.Button
        heartbpmLabel_2              matlab.ui.control.Label
        FinalHeartViewField          matlab.ui.control.NumericEditField
        respbpmLabel_2               matlab.ui.control.Label
        FinalRespirationViewField    matlab.ui.control.NumericEditField
        MovieFig                     matlab.ui.control.UIAxes
        Image3                       matlab.ui.control.Image
        MessageBox                   matlab.ui.control.TextArea
    end


    % RETROSPECTIVE TRIGGERING RECONSTRUCTION
    %
    % Gustav Strijkers / Bram Coolen
    % Academic Medical Center (AMC)
    % Amsterdam, the Netherlands
    % g.j.strijkers@amc.uva.nl / b.f.coolen@amc.uva.nl
    %
    %
    %
    % Version: July 29, 2019
    % Matlab 2019a
    %
    %
    % Version 2.1 changes:
    %
    % - Cleaned-up the code
    % - GIF export file name now includes the number of frames
    % - Automatically start sorting k-space when the number of frames is changed
    % - Added buttons to rotate movie
    % - Added buttons to shift movie in the frame/time dimension
    % - Animated gif export image intensity is scaled by the intensity scaling slider
    % - Trajectory figure added
    % - Full x-scale in plot windows
    % - Added sliders to adjust navigator view range
    % - Parameter no_effective_views is now obsolete
    % 
    % Version 3.0 changes:
    %
    % - Added a matlab Conjugadient Gradient method for CS-reconstruction without Bart toolbox
    % 
    % Version 3.1 changes:
    %
    % - Added the possibility to reconstruction a respiratory CINE
    % - Corrected a bug in the calculation of the total acquisition time
    % - Added the possibility to reconstruct only part of the data
    % - Added detected respiration and heart rate markers and values in navigator power spectrum 
    % - Bug fixed number_of_cardiac frames
    % - Improved check on correct loading of MRD file
    % - Cleaned up the code
    %
    % Version 3.2 changes:
    %
    % - Added edit fields for cardiac and respiratory filter values
    % - Fixed an issue with the image size of the movie in the app
    % - Added a reconstruction progress bar
    %
    % Version 3.3 changes:
    %
    % - Bug fixes
    %
    % Version 3.4 changes:
    % 
    % - Major clean up of the code to accomodate future versions
    % - Added app message box
    % - Bug fixes
    % - Simplification of how to switch the buttons and lights on and off 
    %
    %
    % Version 3.5 changes:
    % - Bug fix for movie frame circular rotation DICOM export
    % - Fix a crash when respiratory or heart filter width > center value
    % 
    % Version 3.6 changes:
    % - Added Total Variation in space regularization - Only works with Bart toolbox
    % - Now reading DICOM information from scanner reconstruction, including correct orientations
    % - VNSA weighting added
    % - Added additional search for DICOM information, compatible with PET/MRI systems
    %


    % global variables within the app
    properties (Access = private)
        
        appdir                      % Application directory
        olddir                      % Starting directory
        datafile                    % mrd data file
        data                        % raw mrd data
        dimensions                  % mrd data matrix dimensions
        parameters                  % mrd meta data
        dimx                        % nr readout points
        dimy                        % nr phase encoding points
        TR                          % repetition time
        acq_dur                     % total acquisition time
        traject_nr                  % trajectory type (0,2,3)
        nr_ksteps                   % number of phase encoding steps in 1 repetition
        nr_klines                   % total number of k-lines
        nr_nav                      % navigator point
        nr_repetitions              % nr of k-space repetitions
        nav_discarded               % number of discarded k-space points between nav and echo
        nav_points_used             % nr of used navigator point
        nav_amplitude               % navigator amplitude
        nav_phase                   % navigator phase
        intensity_scaling           % movie intensity scaling factor
        detectedHR                  % detected heart rate
        heartrate                   % final average heart rate
        detectedRR                  % detected respiratory rate
        resprate                    % final respiratory rate
        heart_bandwidth             % heart rate filter bandwidth
        resp_bandwidth              % respiration rate filter bandwidth
        resp_percentage             % respiration percentage discarded
        resp_window                 % respiration window
        filterorder                 % order of the butterworth filter
        nav_heart                   % filtered heart navigation signal
        nav_resp                    % filtered respiration navigator signal
        splinefactor                % spline interpolation factor
        hpks                        % cardiac trigger points
        rpks                        % respiratory trigger points
        hlocs                       % cardiac trigger point locations
        rlocs                       % respiratory trigger point locations
        nr_frames                   % desired number of movie frames
        kspace                      % the final sorted and normalized k-space
        averages                    % matrix with the number of averages per k-line
        movie                       % movie
        cs_movie                    % compressed sensing movie
        stopmovie                   % flag to stop the movie
        tag                         % filename tag for dicom export
        mrd_import_path             % mrd import path
        dicom_export_path           % dicom export path
        gif_export_path             % gif export path
        bart_detected               % bart toolbox available: true or false
        dwin1                       % datawindow in heartrate plot
        dwin2                       % datawindow in respirationrate plot
        frequency                   % frequency range of power spectrum of navigator
        powerspectrum               % power spectrum of the navigator
        repstart                    % data-window start in units of k-space repetitions 
        repend                      % data-window end in units of k-space repetitions 
        kstart                      % data-window start in units of k-space samples 
        kend                        % data-window end in units of k-space samples 
        progress                    % reconstruction progress bar
        valid_data                  % valid k-space data
                
    end


    methods (Access = private)
        
        
        % -------------------------------------------------
        % Buttons and lights controls
        % -------------------------------------------------
        
        
        function SetLights(app,bitmap)
        % Traffic lights
        
            code = de2bi(bitmap,6);
            
            if code(1) == 0 app.Lamp1.Color = 'Red'; else app.Lamp1.Color = 'Green'; end
            if code(2) == 0 app.Lamp2.Color = 'Red'; else app.Lamp2.Color = 'Green'; end
            if code(3) == 0 app.Lamp3.Color = 'Red'; else app.Lamp3.Color = 'Green'; end
            if code(4) == 0 app.Lamp4.Color = 'Red'; else app.Lamp4.Color = 'Green'; end
            if code(5) == 0 app.Lamp5.Color = 'Red'; else app.Lamp5.Color = 'Green'; end
            if code(6) == 0 app.Lamp6.Color = 'Red'; else app.Lamp6.Color = 'Green'; end
            
            drawnow;
        
        end
        
        
        function SetDashboardButtons(app,bitmap)
        % Dashboard buttons
        
            code = de2bi(bitmap,7);
        
            if code(1) == 0 app.LoadMRDfileButton.Enable = 'off'; else app.LoadMRDfileButton.Enable = 'on'; end
            if code(2) == 0 app.FilterNavigatorButton.Enable = 'off'; else app.FilterNavigatorButton.Enable = 'on'; end
            if code(3) == 0 app.SortKspaceButton.Enable = 'off'; else app.SortKspaceButton.Enable = 'on'; end
            if code(4) == 0 app.ReconstructButton.Enable = 'off'; else app.ReconstructButton.Enable = 'on'; end
            if code(5) == 0 app.ExportDicomButton.Enable = 'off'; else app.ExportDicomButton.Enable = 'on'; end
            if code(6) == 0 app.ExportGIFButton.Enable = 'off'; else app.ExportGIFButton.Enable = 'on'; end
            if code(7) == 0 app.ExitButton.Enable = 'off'; else app.ExitButton.Enable = 'on'; end
            
            drawnow;
        
        end
        
        
        function NavSelButtons(app,on)
        % Navigator selection buttons
            
            if on
                
                app.NavigatorEndEditField.Enable = 'on';
                app.NavigatorNRPointsEditField.Enable = 'on';
                app.NavigatorFlipSwitch.Enable = 'on';
                app.HeartEditField.Enable = 'on';
                app.RespirationEditField.Enable = 'on';
                app.HeartWidthEditField.Enable = 'on';
                app.RespirationWidthEditField.Enable = 'on';
                app.RespirationWindowEditField.Enable = 'on';
                app.ResetFilterValuesButton.Enable = 'on';
                
            else
                
                app.NavigatorEndEditField.Enable = 'off';
                app.NavigatorNRPointsEditField.Enable = 'off';
                app.NavigatorFlipSwitch.Enable = 'off';
                app.HeartEditField.Enable = 'off';
                app.RespirationEditField.Enable = 'off';
                app.HeartWidthEditField.Enable = 'off';
                app.RespirationWidthEditField.Enable = 'off';
                app.RespirationWindowEditField.Enable = 'off';
                app.ResetFilterValuesButton.Enable = 'off';
                
            end
            
            drawnow;
            
        end
        
        
        function NavViewingButtons(app,on)
        % Navigator viewing buttons
            
            if on
                
                app.StartEditField.Enable = 'on';
                app.RangeEditField.Enable = 'on';
                app.StartSlider.Enable = 'on';
                app.RangeSlider.Enable = 'on';
                app.WindowCheckBox.Enable = 'on';
                
                if app.WindowCheckBox.Value == true
                    app.WindowStartEditField.Enable = 'on';
                    app.WindowStartSlider.Enable = 'on';
                    app.WindowEndEditField.Enable = 'on';
                    app.WindowEndSlider.Enable = 'on';
                else
                    app.WindowStartEditField.Enable = 'off';
                    app.WindowStartSlider.Enable = 'off';
                    app.WindowEndEditField.Enable = 'off';
                    app.WindowEndSlider.Enable = 'off';
                end
                
            else
                
                app.StartEditField.Enable = 'off';
                app.RangeEditField.Enable = 'off';
                app.StartSlider.Enable = 'off';
                app.RangeSlider.Enable = 'off';
                app.WindowCheckBox.Enable = 'off';
                app.WindowStartEditField.Enable = 'off';
                app.WindowStartSlider.Enable = 'off';
                app.WindowEndEditField.Enable = 'off';
                app.WindowEndSlider.Enable = 'off';
                
            end
       
            drawnow;
            
        end
        
        
        function CineRecoButtons(app,on)
        % Cine reco buttons
            
            if on
                
                app.FramesEditField.Enable = 'on';
                app.CardiacRespDropDown.Enable = 'on';
                app.WVxyEditField.Enable = 'on';
                app.TVtimeEditField.Enable = 'on';
                app.TVxyEditField.Enable = 'on';
                app.VNSACheckBox.Enable = 'on';
                
            else
                
                app.FramesEditField.Enable = 'off';
                app.CardiacRespDropDown.Enable = 'off';
                app.WVxyEditField.Enable = 'off';
                app.TVtimeEditField.Enable = 'off';
                app.TVxyEditField.Enable = 'off';
                app.VNSACheckBox.Enable = 'off';
                
            end
            
            drawnow;
            
        end
        
        
        function MovieButtons(app,on)
        % Movie Buttons
        
            if on
                
                if app.stopmovie == true
                    
                    app.PlayButton.Enable = 'on';
                    app.StopButton.Enable = 'off';
                    
                else
                    
                    app.PlayButton.Enable = 'off';
                    app.StopButton.Enable = 'on';
                    
                end
                
                app.MovieFrameEditField.Enable = 'on';
                app.MovieScaleSlider.Enable = 'on';
                app.ccButton.Enable = 'on';
                app.ccwButton.Enable = 'on';
                app.FrameIncrButton.Enable = 'on';
                app.FrameDecrButton.Enable = 'on';
                
            else
                
                app.PlayButton.Enable = 'off';
                app.StopButton.Enable = 'off';
                app.MovieFrameEditField.Enable = 'off';
                app.MovieScaleSlider.Enable = 'off';
                app.ccButton.Enable = 'off';
                app.ccwButton.Enable = 'off';
                app.FrameIncrButton.Enable = 'off';
                app.FrameDecrButton.Enable = 'off';
                app.stopmovie = true;
                
            end
            
            drawnow;
            
        end
        
        
        function SwitchOffAllButtons(app)
        
            % Dashboard buttons
            app.SetDashboardButtons(0);
            
            % Navigator selection and filtering
            app.NavSelButtons(false);
            
            % Navigator viewing
            app.NavViewingButtons(false);
            
            % Cine reconstruction buttons
            app.CineRecoButtons(false);
            
            % Movie window
            app.MovieButtons(false);
        
        end


        % ------------------------------------------
        % image acquisition data parameters
        % ------------------------------------------
        
        function DataParametersFcn(app)
            
            app.traject_nr = app.parameters.pe1_order;                                  % k-space trajectroy:  2 = zigzag,  3 = from input array
            app.nr_repetitions = app.dimensions(1);                                     % nr k-space repetitions
            app.repstart = 2;                                                           % repetition 1 is discarded (transient)
            app.repend = app.nr_repetitions;                                            % can be adapated to reconstruct part of the data
            app.nav_discarded = 45-app.nr_nav;                                          % additionally discarded k-space samples (during phase encoding / readout dephaser)
            app.dimx = app.dimensions(6) - app.nr_nav - app.nav_discarded;              % nr readout points (no_samples)
            app.TR = app.parameters.tr;                                                 % repetition time [ms]
            app.splinefactor = 60;                                                      % data interpolation factor to prevent navigator discretization by TR
            [~,app.tag,~] = fileparts(app.datafile);                                    % dicomexport filename tag
            app.intensity_scaling = 0.75;                                               % intensity scaling for movie
            app.heart_bandwidth = app.HeartWidthEditField.Value;                        % cardiac bandpass filter bandwidth, default = 150
            app.resp_bandwidth = app.RespirationWidthEditField.Value;                   % respiratory bandpass filter bandwidth, default = 20
            app.resp_percentage = app.RespirationWindowEditField.Value;                 % percentage of data discarded during respiration, default = 30%
            app.filterorder = 4;                                                        % butterworth filter order
            app.nr_frames = app.FramesEditField.Value;                                  % default number of cardiac frames
                        
            switch app.traject_nr
                case 0
                    app.TrajectoryViewField.Value = 'linear';
                    app.dimy = app.parameters.NO_VIEWS;                                 % nr phase encoding steps (no_views)
                    app.nr_ksteps = app.dimy;                                           % nr k-space points per repetition = nr phase encoding steps
                    app.nr_klines = app.nr_repetitions*app.dimy;                        % total number of k-lines that are measured
                    plot(app.TrajectoryFig, linear_trajectory(app.dimy));               % plot the trajectory
                    xlim(app.TrajectoryFig,[1 length(linear_trajectory(app.dimy))-1]);
                case 2
                    app.TrajectoryViewField.Value = 'zigzag';
                    app.dimy = app.parameters.NO_VIEWS;                                 % nr phase encoding steps (no_views)
                    app.nr_ksteps = app.dimy;                                           % nr k-space points per repetition = nr phase encoding steps
                    app.nr_klines = app.nr_repetitions*app.dimy;                        % total number of k-lines that are measured
                    plot(app.TrajectoryFig, zigzag_trajectory(app.dimy));               % plot the trajectory
                    xlim(app.TrajectoryFig,[1 length(zigzag_trajectory(app.dimy))-1]);
                case 3
                    app.TrajectoryViewField.Value = 'user defined';
                    app.dimy = max(app.parameters.gp_var_mul)-min(app.parameters.gp_var_mul)+1;     % effective nr phase encoding steps (no_effective_views)
                    app.nr_ksteps = app.parameters.NO_VIEWS;                                        % nr k-space trajectory points (no_views) in table
                    app.nr_klines = app.nr_repetitions*app.nr_ksteps;                               % total number of k-lines that are measured
                    plot(app.TrajectoryFig, array_trajectory(app.parameters));                      % plot the trajectory
                    xlim(app.TrajectoryFig,[1 length(array_trajectory(app.parameters))-1]);
            end
            
            % total acquisition time in seconds
            app.acq_dur = app.nr_klines*app.TR/1000;                
            
            % display the acquisitionparameters
            app.NyViewField.Value = app.dimy;
            app.NxViewField.Value = app.dimx;
            app.KlinesRepetitionViewField.Value = app.nr_ksteps;
            app.RepetitionsViewField.Value = app.nr_repetitions;
            app.NrKlinesViewField.Value = app.nr_klines;
            app.TRViewField.Value = app.TR;
            app.AcquisitionTimeMinViewField.Value = app.acq_dur/60;
            
            % define the ranges of the display range sliders
            app.StartSlider.Limits = [1, app.nr_klines-1];
            app.RangeSlider.Value = 600;
            
            % data window limits
            app.WindowStartEditField.Limits = [0,round(app.acq_dur)];
            app.WindowStartSlider.Limits = [0,round(app.acq_dur)];
            app.WindowEndEditField.Limits = [0,round(app.acq_dur)];
            app.WindowEndSlider.Limits = [0,round(app.acq_dur)];
            app.WindowStartEditField.Value = 0;
            app.WindowStartSlider.Value = 0;
            app.WindowEndEditField.Value = round(app.acq_dur);
            app.WindowEndSlider.Value = round(app.acq_dur);
                        
        end
        
        
        % ------------------------------------------
        % Plotting figures and images
        % ------------------------------------------


        % plot the raw navigator signals
        function PlotRawNavigatorFcn(app)
            
            % detrend the offset
            trace1 = detrend(app.nav_amplitude(app.StartEditField.Value:app.StartEditField.Value+app.RangeEditField.Value-1));
            maxplt = max(trace1);
            minplt = min(trace1);
            
            % plot the navigator
            plot(app.RawNavFig, trace1);
            xlim(app.RawNavFig, [0 length(trace1)]);
            ylim(app.RawNavFig, [1.1*minplt 1.1*maxplt]);
            drawnow;
                        
        end
        
        
        % plot the navigator with cardiac filtering
        function PlotCardiacNavigatorFcn(app)
                        
            cla(app.CardiacNavFig);
            hold(app.CardiacNavFig,'on');
            
            % detrend offset
            trace1 = detrend(app.nav_amplitude(app.StartEditField.Value:app.StartEditField.Value+app.RangeEditField.Value-1));  
            trace2 = detrend(app.nav_heart(app.StartEditField.Value:app.StartEditField.Value+app.RangeEditField.Value-1));      
                                    
            % plot the raw navigator
            plot(app.CardiacNavFig, trace1);
            
            % plot the navigator filtered for cardiac motion
            plot(app.CardiacNavFig, trace2,'LineWidth',2);
            
            % set plot axes
            maxplt = 1.1*max(trace1);
            minplt = 1.1*min(trace1);
            xlim(app.CardiacNavFig, [0 length(trace1)]);
            ylim(app.CardiacNavFig, [minplt maxplt]);
            
            hold(app.CardiacNavFig,'off');
            drawnow;
            
        end
        
        
        % Plot the navigator with respiratory filtering
        function PlotRespirationNavigatorFcn(app)
            
            cla(app.RespirationNavFig);
            hold(app.RespirationNavFig,'on');
            
            % detrend offset
            trace1 = detrend(app.nav_amplitude(app.StartEditField.Value:app.StartEditField.Value+app.RangeEditField.Value-1));   
            trace2 = detrend(app.nav_resp(app.StartEditField.Value:app.StartEditField.Value+app.RangeEditField.Value-1));  
            
            % Plot respiratory window masks in transparent red
            maxplt = 1.1*max(trace1);
            minplt = 1.1*min(trace1);
            for i=app.StartEditField.Value:app.StartEditField.Value+app.RangeEditField.Value-1
                if app.resp_window(i)==1 
                    win=plot(app.RespirationNavFig,[i-app.StartEditField.Value,i-app.StartEditField.Value],[minplt,maxplt],'red','LineWidth',1); 
                    win.Color(4) = 0.1; % transparancy
                end
            end
                        
            % Plot raw navigator
            plot(app.RespirationNavFig, trace1);
            
            % Plot navigator signal filtered for respiratory motion
            plot(app.RespirationNavFig, trace2,'LineWidth',3);
            
            % set plot axes
            xlim(app.RespirationNavFig, [0 length(trace1)]);
            ylim(app.RespirationNavFig, [minplt maxplt]);
            
            hold(app.RespirationNavFig,'off'); 
            drawnow;
            
        end
             
        
        % Plot the current movie frame        
        function PlotCurrentMovieFrameFcn(app)
                    
            scale = 32767*app.intensity_scaling;
            app.MovieFig.Position = [214,43,260,260];
            [dx,dy]=size(squeeze(app.movie(:,:,app.MovieFrameEditField.Value)));
            xlim(app.MovieFig,[1,dx]);
            ylim(app.MovieFig,[1,dy]);
            imshow(squeeze(app.movie(:,:,app.MovieFrameEditField.Value)),[0,round(scale*app.MovieScaleSlider.Value)],'Parent',app.MovieFig);
            drawnow;
            
        end
        
        
        % Plot the data window in time-heartrate and time-respirationrate plots 
        function PlotDataWindowFcn(app)
            
            % Delete previous windows
            delete(app.dwin1); 
            delete(app.dwin2);
                    
            if app.WindowCheckBox.Value == true
                % Time-heartrate plot
                hold(app.HeartRateTimeFig,'on');
                pgon = polyshape([app.WindowStartEditField.Value app.WindowStartEditField.Value app.WindowEndEditField.Value app.WindowEndEditField.Value],[5 995 995 5]);
                app.dwin1 = plot(app.HeartRateTimeFig, pgon,'FaceColor','green','FaceAlpha',0.15,'LineStyle','none');
                axis(app.HeartRateTimeFig,[0 app.TR*app.hlocs(end)/1000 0 1000]);
                hold(app.HeartRateTimeFig,'off');
                drawnow;
                
                % Time-respirationrate plot
                hold(app.RespirationRateTimeFig,'on');
                pgon = polyshape([app.WindowStartEditField.Value app.WindowStartEditField.Value app.WindowEndEditField.Value app.WindowEndEditField.Value],[0.5 99.5 99.5 0.5]);
                app.dwin2 = plot(app.RespirationRateTimeFig, pgon,'FaceColor','green','FaceAlpha',0.15,'LineStyle','none');
                axis(app.RespirationRateTimeFig,[0 app.TR*app.rlocs(end)/1000 0 100]);
                hold(app.RespirationRateTimeFig,'off');
                drawnow;
                
                % Adjust the acquisition paramters
                new_number_of_repetitions = round(1000*app.WindowEndEditField.Value/(app.TR*app.nr_ksteps))-ceil(1000*(app.WindowStartEditField.Value+0.001)/(app.TR*app.nr_ksteps))+1;
                app.RepetitionsViewField.Value = new_number_of_repetitions;
                app.NrKlinesViewField.Value = new_number_of_repetitions * app.KlinesRepetitionViewField.Value;
                app.AcquisitionTimeMinViewField.Value = app.NrKlinesViewField.Value * (app.TR/1000) / 60;
            else
                % Reset the acquisition parameters
                app.RepetitionsViewField.Value = app.nr_repetitions;
                app.NrKlinesViewField.Value = app.nr_klines;
                app.AcquisitionTimeMinViewField.Value = app.acq_dur/60;
            end
                      
        end
         
        
        % Calculates the power spectrum of the raw navigator signal within the boundaries of the data-window
        function CalculateNavigatorSpectrumFcn(app)
            
            % calculate the data-window in terms of k-space samples
            if app.WindowCheckBox.Value == true
                app.kstart = ceil(1000*(app.WindowStartEditField.Value+0.001)/app.TR);
                app.kend = round(1000*(app.WindowEndEditField.Value)/app.TR);
                if app.kend > app.nr_klines
                    app.kend = app.nr_klines;
                end
            else % full range
                app.kstart = 1;
                app.kend = app.nr_klines;
            end
            
            % calculate the data-window in units of k-space repetitions
            app.repstart = ceil(app.kstart/app.nr_ksteps);
            app.repend = round(app.kend/app.nr_ksteps);
            
            % determine the power spectrum of the navigator
            [app.detectedHR,app.detectedRR,app.powerspectrum,app.frequency] = determine_power_spectrum(app.nav_amplitude,app.TR,app.dimy,app.kstart,app.kend);
            
            % update the detected heartrate and respirationrate fields
            app.HeartEditField.Value = app.detectedHR;
            app.RespirationEditField.Value = app.detectedRR;
            
        end
        
        
        % Plots the power spectrum of the raw navigator signal
        function PlotNavigatorSpectrumFcn(app)
            
            % plot power spectrum
            cla(app.SpectrumFig);
            hold(app.SpectrumFig,'on');
            plot(app.SpectrumFig,app.frequency,app.powerspectrum);
            ymax = 1.1*max(app.powerspectrum(5:800));
            ymin = -0.05*ymax;
            xlim(app.SpectrumFig, [5 800]);
            ylim(app.SpectrumFig, [ymin, ymax]);
            
            % plot the butter filter for cardiac motion filtering
            flt = filter_shape(app.TR,app.detectedHR,app.heart_bandwidth,app.filterorder); % heart filter shape
            pgon = polyshape(flt(:,1),ymax*flt(:,2));
            area(app.SpectrumFig,flt(:,1),0.95*ymax*flt(:,2),'FaceColor','green','FaceAlpha',0.1,'EdgeColor','green','LineStyle', 'none');
            plot(app.SpectrumFig,[app.detectedHR,app.detectedHR],[0,ymax],'green','LineWidth',1,'LineStyle','--'); 
            text(app.SpectrumFig,app.detectedHR+10,0.9*ymax,[num2str(app.detectedHR),' bpm']);
            
            % plot the butter filter for the repiration motion filtering
            flt = filter_shape(app.TR,app.detectedRR,app.resp_bandwidth,app.filterorder); % resp filter shape
            pgon = polyshape(flt(:,1),ymax*flt(:,2));
            area(app.SpectrumFig,flt(:,1),0.95*ymax*flt(:,2),'FaceColor','green','FaceAlpha',0.1,'EdgeColor','green','LineStyle', 'none');
            plot(app.SpectrumFig,[app.detectedRR,app.detectedRR],[0,ymax],'green','LineWidth',1,'LineStyle','--'); 
            text(app.SpectrumFig,app.detectedRR+10,0.9*ymax,[num2str(app.detectedRR),' bpm']);
            
            hold(app.SpectrumFig,'off');
            drawnow;
         
        end
        
    end


    % ----------------------------------------------
    % Public functions, available outside the app
    % ----------------------------------------------


    methods (Access = public)
        
        
        function TextMessage(app, message)
            
            % Text messages in the app
            app.MessageBox.Value = [message ; app.MessageBox.Value];
            drawnow;
            
        end % TextMessage
        
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartupFcn(app)
            
            % Start the parallel pool if it is not running already
            p = gcp('nocreate');
            if isempty(p) 
                parpool; 
            end
            
            % Empty file import and export paths
            app.mrd_import_path = '';
            app.dicom_export_path = '';
            app.gif_export_path = '';
            
            % Add subfolders to search path
            if (~isdeployed)
                app.olddir = pwd;
                info = dir(which('retrospective.mlapp'));
                app.appdir = info.folder;
                cd(app.appdir);
                addpath(genpath(pwd));
            end
            
            % Add the bart path to the environment variable if available
            app.bart_detected = false;
            if isfile('bartpath.txt')
                folder_name = fileread('bartpath.txt');
                if exist(folder_name, 'dir')
                    app.TextMessage('BART toolbox detected ...');
                    app.bart_detected = true;
                    app.RecoEditField.Value = 'BART';
                    setenv('TOOLBOX_PATH', folder_name);
                end
            end
            
            % Check whether mrd-path is defined in mrdpath.txt file
            if isfile('mrdpath.txt')
                folder_name = fileread('mrdpath.txt');
                if exist(folder_name, 'dir')
                    app.TextMessage(strcat('MRD directory =',{' '},folder_name));
                    app.mrd_import_path = folder_name;
                end
            end
            
            % disable unnecessary warnings
            warning off;
            
            % set buttons
            app.LoadMRDfileButton.Enable = 'on';
            drawnow;
                    
        end

        % Callback function: DataFile, LoadMRDfileButton
        function LoadMRDfileButtonPushed(app, event)
            
            % Indicate busy
            app.SetLights(0);
            app.Lamp1.Color = 'Yellow';
            app.SwitchOffAllButtons;
            
            % Select a MRD file
            app.RetrospectiveUIFigure.Visible = 'off';
            [mrdfile,app.mrd_import_path] = uigetfile(strcat(app.mrd_import_path,filesep,'*.MRD'));
            app.RetrospectiveUIFigure.Visible = 'on';
            
            app.valid_data = false;
            
            if ischar(mrdfile)
                % MRD file selected
                app.DataFile.Value = mrdfile;
                app.datafile = [app.mrd_import_path,mrdfile];
                
                % Load the data
                app.TextMessage('Loading k-space data ...');
                [app.data,app.dimensions,app.parameters] = Get_mrd_3D4(app.datafile,'seq','cen');
                
                % check if the data is a valid file with a motion navigator acquisition
                if isfield(app.parameters,'slice_nav')
                    if app.parameters.slice_nav == 1 & app.parameters.no_samples_nav > 0
                        app.nr_nav = app.parameters.no_samples_nav;                     % initial number of navigator points
                        DataParametersFcn(app);                                         % determine all data parameters
                        NavPointEditFieldValueChanged(app, event);
                        app.valid_data = true;
                    end
                end
            end
                
            if app.valid_data
                
                % reset buttons
                app.SetLights(1);
                app.SetDashboardButtons(67);
                app.stopmovie = true; 
                app.NavSelButtons(true);
                 
            else               
                
                % not a valid MRD file with navigator
                f = app.RetrospectiveUIFigure;
                uialert(f,'Please select a valid MRD file','Error');
                app.mrd_import_path = '';
                app.LoadMRDfileButton.Enable = 'on';
                app.Lamp1.Color = 'Red';
                
            end
            
        end

        % Value changed function: NavigatorEndEditField, 
        % NavigatorFlipSwitch, NavigatorNRPointsEditField
        function NavPointEditFieldValueChanged(app, event)
            
            % update the used navigator point and the number of navigator points used
            if app.NavigatorNRPointsEditField.Value > app.NavigatorEndEditField.Value 
                app.NavigatorNRPointsEditField.Value = app.NavigatorEndEditField.Value; 
            end
            app.nr_nav = app.NavigatorEndEditField.Value;
            app.nav_points_used = app.NavigatorNRPointsEditField.Value;
            
            % update the data parameters
            DataParametersFcn(app);
            
            % switch the data-window off
            app.WindowCheckBox.Value = false;
            PlotDataWindowFcn(app);
            
            % determine the value of the switch to flip the navigator 
            switch app.NavigatorFlipSwitch.Value
                case 'Up'
                    pos_min = 1;
                case 'Down'
                    pos_min = -1;
            end
                                    
            % extract the navigator signals
            [app.nav_amplitude,app.nav_phase] = extractnavigator(app.data,app.nr_nav,app.nav_points_used,pos_min);
                        
            % plot the raw navigator signal
            PlotRawNavigatorFcn(app);
                        
            % determine and plot the power spectrum of the raw navigator signal
            CalculateNavigatorSpectrumFcn(app);
            PlotNavigatorSpectrumFcn(app);
                      
            % reset buttons
            app.SetLights(1);
            app.SwitchOffAllButtons;
            app.SetDashboardButtons(67);
            app.NavSelButtons(true);
            
        end

        % Value changed function: HeartEditField, 
        % HeartWidthEditField, RespirationEditField, 
        % RespirationWidthEditField, RespirationWindowEditField
        function FilterEditFieldValueChanged(app, event)
            
            if app.HeartWidthEditField.Value > app.HeartEditField.Value 
                app.HeartWidthEditField.Value = app.HeartEditField.Value;
            end
            
            if app.RespirationWidthEditField.Value > app.RespirationEditField.Value
                app.RespirationWidthEditField.Value = app.RespirationEditField.Value;
            end
        
            app.detectedHR = app.HeartEditField.Value;
            app.heart_bandwidth = app.HeartWidthEditField.Value;
            app.detectedRR = app.RespirationEditField.Value;
            app.resp_bandwidth = app.RespirationWidthEditField.Value;
            app.resp_percentage = app.RespirationWindowEditField.Value;
            
            FilterNavigatorButtonPushed(app, event);
            PlotNavigatorSpectrumFcn(app);
            
        end

        % Button pushed function: ResetFilterValuesButton
        function ResetFilterValuesButtonPushed(app, event)
            
            % reset filter values
            app.heart_bandwidth = 100;
            app.HeartWidthEditField.Value = 100;                        
            app.resp_bandwidth = 20;
            app.RespirationWidthEditField.Value = 20;                   
            app.resp_percentage = 30;
            app.RespirationWindowEditField.Value = 30;                 
            
            CalculateNavigatorSpectrumFcn(app);
            FilterNavigatorButtonPushed(app, event);
            PlotNavigatorSpectrumFcn(app);
        
        end

        % Button pushed function: FilterNavigatorButton
        function FilterNavigatorButtonPushed(app, event)
            
            % indicate busy
            app.SetLights(1);
            app.Lamp2.Color = 'Yellow';
            app.SwitchOffAllButtons;
            
            app.TextMessage('Filtering the navigator ...');
                        
            % filter for cardiac motion
            app.nav_heart = filter_nav_bp(app.nav_amplitude,app.TR,app.detectedHR,app.heart_bandwidth,app.filterorder);
                       
            % filter for respiratory motion
            app.nav_resp = filter_nav_bp(app.nav_amplitude,app.TR,app.detectedRR,app.resp_bandwidth,app.filterorder);
            
            % extract cardiac trigger points
            [app.hpks,app.hlocs]=trigpoints(app.nav_heart,app.TR,app.detectedHR,app.splinefactor);
            hr = calc_heartrate(app.hlocs,app.TR);
            hrf = filter_lp(hr,app.TR,50);
            
            % determine the mean cardiac rate in the data-window
            idx1 = find(app.TR*app.hlocs(1:end)'/1000 > app.WindowStartEditField.Value);     % find the start index, since the window is defined in seconds
            idx2 = find(app.TR*app.hlocs(1:end)'/1000 > app.WindowEndEditField.Value);       % find the end index, since the window is defined in seconds
            if isempty(idx2) | idx2>size(hr,2)                                              % when the data-window has the full range the index is not found
                idx2 = size(hr,2);                                                          % in that case index = last index of the heartrate array
            end
            app.heartrate = mean(hr(idx1(1):idx2(1)));                                 % take the mean of the heartrate only within the boundaries of the data-window
            app.FinalHeartViewField.Value = round(app.heartrate);                      % display the heartrate next to the movie
            
            % show the time-heartrate plot
            cla(app.HeartRateTimeFig);
            hold(app.HeartRateTimeFig,'on');
            plot(app.HeartRateTimeFig, app.TR*app.hlocs(1:end-1)'/1000, hr);                  % heartrate   
            plot(app.HeartRateTimeFig, app.TR*app.hlocs(1:end-1)'/1000, hrf,'LineWidth',3);   % trend line
            axis(app.HeartRateTimeFig,[0 app.TR*app.hlocs(end)/1000 0 1000]);
            hold(app.HeartRateTimeFig,'off');
            drawnow;
            
            % extract respiratory trigger points
            [app.rpks,app.rlocs]=trigpoints(app.nav_resp,app.TR,app.detectedRR,app.splinefactor);
            resp = calc_resprate(app.rlocs,app.TR);
            respf = filter_lp(resp,app.TR,150);
            app.resp_window = respiratory_window(app.rlocs,mean(respf),app.resp_percentage,app.TR,app.nr_klines);
            
            % determine the mean respiration rate in the data-window
            idx1 = find(app.TR*app.rlocs(1:end)'/1000 > app.WindowStartEditField.Value);    % find the start index, since the window is defined in seconds
            idx2 = find(app.TR*app.rlocs(1:end)'/1000 > app.WindowEndEditField.Value);      % find the end index, since the window is defined in seconds
            if isempty(idx2) | idx2>size(resp,2)                                            % when the data-window has the full range the index is not found
                idx2 = size(resp,2);                                                        % in that case index = last index of the respirationrate array
            end
            app.resprate = mean(resp(idx1(1):idx2(1)));                                % take the mean of the respirationrate only within the boundaries of the data-window
            app.FinalRespirationViewField.Value = round(app.resprate);                 % display respiration rate next to the movie
            
            % show the time-respirationrate plot
            cla(app.RespirationRateTimeFig);
            hold(app.RespirationRateTimeFig,'on');
            plot(app.RespirationRateTimeFig, app.TR*app.rlocs(1:end-1)'/1000,resp);                 % respiration rate
            plot(app.RespirationRateTimeFig, app.TR*app.rlocs(1:end-1)'/1000,respf,'LineWidth',3);  % trend line
            axis(app.RespirationRateTimeFig,[0 app.TR*app.rlocs(end)/1000 0 100]);
            hold(app.RespirationRateTimeFig,'off');
            drawnow;
                           
            % Plot the datawindow in the time-heartrate and time-respirationrate plots
            PlotDataWindowFcn(app);  
            
            % show the samples-navigator plots
            PlotCardiacNavigatorFcn(app);
            PlotRespirationNavigatorFcn(app);
            
            % reset buttons
            app.SetLights(3);
            app.SetDashboardButtons(71);
            app.NavSelButtons(true);
            app.NavViewingButtons(true);
            app.CineRecoButtons(true);
            app.stopmovie = true;  
            drawnow;
            
        end

        % Value changed function: RangeEditField, StartEditField
        function DisplayEditFieldValuesChanged(app, event)
         
            value1 = app.StartEditField.Value;
            value2 = app.RangeEditField.Value;
            
            % Range should be at least 20
            if value2 < 20
                value2 = 20; 
            end
            
             % Range should not exceed 2000, else plot gets really slow and cluttered
            if value2 > 2000
                value2 = 2000; 
            end
            
            % Start + Range should not exceed the number of available k-lines
            if value1 + value2 > app.nr_klines
                value1 = app.nr_klines - value2;
            end
            
            % Report the values back to the user-interface
            app.StartEditField.Value = value1;
            app.RangeEditField.Value = value2;       
        
            % Adjust the sliders also
            app.StartSlider.Value = app.StartEditField.Value;
            app.RangeSlider.Value = app.RangeEditField.Value;
            
            % Plot the raw navigator, cardiac filtered, and respiration filtered
            PlotRawNavigatorFcn(app);
            PlotCardiacNavigatorFcn(app);
            PlotRespirationNavigatorFcn(app);
            
        end

        % Value changed function: RangeSlider, StartSlider
        function DisplaySlidersValueChanged(app, event)
             
            % Set the field values to match the sliders
            app.StartEditField.Value = round(app.StartSlider.Value);
            app.RangeEditField.Value = round(app.RangeSlider.Value);
            
            % Proceed as if the field values were changed
            DisplayEditFieldValuesChanged(app, event);
            
        end

        % Value changed function: WindowCheckBox
        function WindowCheckBoxValueChanged(app, event)
                       
            % Plot the datawindow in the time-heartrate and time-respirationrate plots
            PlotDataWindowFcn(app);
            
            % Calculate and plot the power spectrum of the raw navigator signal
            CalculateNavigatorSpectrumFcn(app);
            PlotNavigatorSpectrumFcn(app);
            
            % Reset buttons
            app.SetLights(1);
            app.SwitchOffAllButtons;
            app.SetDashboardButtons(67);
            app.NavSelButtons(true);
            app.NavViewingButtons(true);
            app.stopmovie = true;  
            drawnow;
              
        end

        % Value changed function: WindowEndEditField, 
        % WindowStartEditField
        function WindowEditFieldValueChanged(app, event)
            
            % read the values from the user interface
            value1 = app.WindowStartEditField.Value;        % start of the data-window in units of seconds
            value2 = app.WindowEndEditField.Value;          % end of the data-window in units of seconds
            
            % End should be bigger than Start and length of the window should be at least 10 (arbitrary) repetitions
            if value2-value1 < round(10 * app.nr_ksteps * app.TR / 1000)
                value2 = value1 + round(10 * app.nr_ksteps * app.TR / 1000); 
            end
            
            % End should be smaller than total acquisition time and length of the window should be at least 10 (arbitrary) repetitions
            if value2 > round(app.acq_dur)
                value2 = round(app.acq_dur);
                value1 = value2 - round(10 * app.nr_ksteps * app.TR / 1000);
            end
            
            % set the values and sliders
            app.WindowStartEditField.Value = value1;
            app.WindowStartSlider.Value = value1;
            app.WindowEndEditField.Value = value2;
            app.WindowEndSlider.Value = value2;
                      
            % Plot the datawindow in the time-heartrate and time-respirationrate plots
            PlotDataWindowFcn(app);
                         
            % Calculate and plot the power spectrum of the raw navigator signal
            CalculateNavigatorSpectrumFcn(app);
            PlotNavigatorSpectrumFcn(app);
            
            % Reset buttons
            app.SetLights(1);
            app.SwitchOffAllButtons;
            app.SetDashboardButtons(67);
            app.NavSelButtons(true);
            app.NavViewingButtons(true);
            app.stopmovie = true;  
            drawnow;
                        
        end

        % Value changed function: WindowEndSlider, WindowStartSlider
        function WindowSliderValueChanged(app, event)
            
            % Set the field values to match the sliders
            app.WindowStartEditField.Value = app.WindowStartSlider.Value;
            app.WindowEndEditField.Value = app.WindowEndSlider.Value;
            
            % Proceed as if the field values were changed
            WindowEditFieldValueChanged(app, event);
            
        end

        % Value changed function: CardiacRespDropDown, 
        % FramesEditField
        function FramesEditFieldValueChanged(app, event)
        
            % If running, stop the movie
            StopButtonPushed(app, event);
        
            % New number of frames
            app.nr_frames = app.FramesEditField.Value;
                       
            % Reset buttons
            app.SetLights(3);
            app.SwitchOffAllButtons;
            app.SetDashboardButtons(71);
            app.NavSelButtons(true);
            app.NavViewingButtons(true);
            app.CineRecoButtons(true);
            app.stopmovie = true;  
            drawnow;
             
            % Automatically start sorting when number of frames has been changed
            SortKspaceButtonPushed(app, event);        
                        
        end

        % Button pushed function: SortKspaceButton
        function SortKspaceButtonPushed(app, event)
                        
            % Indicate busy
            app.SetLights(3);
            app.Lamp3.Color = 'Yellow';
            app.SwitchOffAllButtons;
                        
            app.TextMessage('Sorting k-space data ...');
            
            % Extract all the raw k-space imaging data
            raw = extractdata(app.data,app.nr_nav + app.nav_discarded); 

            % Determine whether to sort cardiac or respiratory phases
            switch app.CardiacRespDropDown.Value
                case 'cardiac'
                    number_of_cardiac_frames = app.nr_frames;
                    number_of_respiratory_frames = 1;
                case 'respiratory'
                    number_of_cardiac_frames = 1;
                    number_of_respiratory_frames = app.nr_frames;
            end
            
            % Determine the respiratory and cardiac bin times
            [bin_times_card,bin_times_resp] = assign_bin_times(app.hlocs,number_of_cardiac_frames,app.rlocs,number_of_respiratory_frames);      
            
            % Array which is filled with assignment of all k-lines to specific cardiac and respiratory frames
            [card_bin_ass,resp_bin_ass] = assign_bin_frames(bin_times_card,bin_times_resp,app.resp_window,app.nr_klines,number_of_cardiac_frames,number_of_respiratory_frames);

            % Trajectory (linear, zigzag, or user-defined)
            switch app.traject_nr
                case 0
                    trajectory = linear_trajectory(app.dimy);
                case 2
                    trajectory = zigzag_trajectory(app.dimy);
                case 3
                    trajectory = array_trajectory(app.parameters); 
            end
                        
            % Sort k-space
            % Only use the data from app.repstart to app.repend, determined by the data-window
            [app.kspace,app.averages] = fill_kspace(raw(app.repstart:app.repend,:,:),app.repstart,number_of_cardiac_frames,number_of_respiratory_frames,app.dimy,app.nr_ksteps,app.dimx,card_bin_ass,resp_bin_ass,trajectory);
            
            % K-space statistics
            app.FillingViewField.Value = round(100*nnz(app.averages)/numel(app.averages));
            app.AveragesViewField.Value = mean2(app.averages);
            
            % Reset buttons
            app.SetLights(7);
            app.SetDashboardButtons(79);
            app.NavSelButtons(true);
            app.NavViewingButtons(true);
            app.CineRecoButtons(true);
            app.stopmovie = true;  
            drawnow;
               
        end

        % Value changed function: TVtimeEditField, TVxyEditField, 
        % VNSACheckBox, WVxyEditField
        function RecoParEditFieldValueChanged(app, event)
            
            % Reset buttons
            app.SetLights(7);
            app.SwitchOffAllButtons;
            app.SetDashboardButtons(79);
            app.NavSelButtons(true);
            app.NavViewingButtons(true);
            app.CineRecoButtons(true);
            app.stopmovie = true;  
            drawnow;
            
        end

        % Button pushed function: ReconstructButton
        function ReconstructButtonPushed(app, event)
            
            % Indicate busy         
            app.SetLights(7);
            app.Lamp4.Color = 'Yellow';
            app.SwitchOffAllButtons;
                                    
            % Squeeze the respiratory or cardiac dimension
            switch app.CardiacRespDropDown.Value
                case 'cardiac'
                    kspace1 = squeeze(app.kspace(1,:,:,:));     % first dimension is reserved for respiration states
                    averages1 = squeeze(app.averages(1,:,:,:));    
                case 'respiratory'
                    kspace1 = squeeze(app.kspace(:,1,:,:));     % second dimension are the cardiac frames
                    averages1 = squeeze(app.averages(:,1,:,:));    
            end
           
            % CS reconstruction
            if app.bart_detected == true
                % Perform cs reconstruction with the Bart toolbox, preferred option
                app.TextMessage('Reconstructing the data with BART toolbox ...');
                app.ProgressGauge.Value = 0;
                drawnow;
                app.cs_movie = cs_reco(app,kspace1,averages1,app.WVxyEditField.Value,app.TVtimeEditField.Value,app.TVxyEditField.Value,app.VNSACheckBox.Value);
                app.ProgressGauge.Value = 100;
            else
                % Perform cs reconstruction in matlab, slow but works
                app.TextMessage('Reconstructing the data with MATLAB ...');
                app.TextMessage('TVxy regularization and VNSA disabled ! ...');
                app.TextMessage('Slow reconstruction, please be patient ...');
                app.TextMessage('Use the BART toolbox for fast reconstruction ...');
                app.ProgressGauge.Value = 0;
                drawnow;
                app.cs_movie = cs_reco_mat(app,kspace1,averages1,app.WVxyEditField.Value,app.TVtimeEditField.Value);                
            end
            
            % Normalize image intensity somewhat
            app.cs_movie = norm_images(app.cs_movie); 
                        
            % Permutate for viewing in correct orientation in the app 
            app.movie = permute(app.cs_movie,[2,3,1]);
                                 
            % Reset buttons
            app.SetLights(15);
            app.SetDashboardButtons(127);
            app.NavSelButtons(true);
            app.NavViewingButtons(true);
            app.CineRecoButtons(true);
            app.MovieButtons(true);
            drawnow;
            
            % Start the movie
            PlayButtonPushed(app, event);
            
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            
            % Movie is running
            app.stopmovie = false;
            app.TextMessage('Movie playing ...');
            
            % Buttons
            app.MovieButtons(true);
            app.ExitButton.Enable = 'off';
            
            counter = 1;
            
            scale = 32767*app.intensity_scaling;
            drawnow;
            
            % Play the movie continuously until app.stopmovie==true
            while app.stopmovie == false
                imshow(squeeze(app.movie(:,:,counter)),[0,round(scale*app.MovieScaleSlider.Value)],'InitialMagnification','fit','Parent',app.MovieFig);
                drawnow;
                counter = counter + 1;
                if counter>app.nr_frames 
                    counter=1; 
                end
                app.MovieFrameEditField.Value = counter;
            end              
            
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            
            % Stop the movie
            app.stopmovie = true;  
            app.TextMessage('Stopping the movie ...');
             
            % Reset buttons
            app.MovieButtons(true);
            app.ExitButton.Enable = 'on';
            drawnow;
            
        end

        % Value changed function: MovieFrameEditField
        function MovieFrameEditFieldValueChanged(app, event)
            value = app.MovieFrameEditField.Value;
            
             % If running, stop the movie
            StopButtonPushed(app, event);
            
            % Check if input value <= number of cardiac frames
            if value>app.nr_frames 
                app.MovieFrameEditField.Value = app.nr_frames; 
            end
            
            % Show the movie frame
            PlotCurrentMovieFrameFcn(app);
            
        end

        % Value changed function: MovieScaleSlider
        function MovieScaleSliderValueChanged(app, event)
                        
            % Show the movie frame
            PlotCurrentMovieFrameFcn(app);
            
        end

        % Button pushed function: ccButton
        function ccButtonPushed(app, event)
            
           % Rotate movie clock-wise
           app.movie = rot90(app.movie,-1);
           
           % Show the movie frame
           PlotCurrentMovieFrameFcn(app);
           
           % Reset buttons
           app.Lamp5.Color = 'Red';
           app.Lamp6.Color = 'Red';
           
        
        end

        % Button pushed function: ccwButton
        function ccwButtonPushed(app, event)
            
            % rotate movie counter-clock-wise
            app.movie = rot90(app.movie);
            
            % show the movie frame
            PlotCurrentMovieFrameFcn(app);
            
            % Reset buttons
            app.Lamp5.Color = 'Red';
            app.Lamp6.Color = 'Red';
        
        end

        % Button pushed function: FrameIncrButton
        function FrameIncrButtonPushed(app, event)
            
            % Rotate movie +1 frame along the time/frame axis
            app.movie = circshift(app.movie,-1,3);
            app.cs_movie = circshift(app.cs_movie,-1,1);
            
            % Show the movie frame
            PlotCurrentMovieFrameFcn(app);
            
            % Reset buttons
            app.Lamp5.Color = 'Red';
            app.Lamp6.Color = 'Red';
        
        end

        % Button pushed function: FrameDecrButton
        function FrameDecrButtonPushed(app, event)
            
            % Rotate movie -1 frame along the time/frame axis
            app.movie = circshift(app.movie,+1,3);
            app.cs_movie = circshift(app.cs_movie,+1,1);
            
            % Show the movie frame
            PlotCurrentMovieFrameFcn(app);
            
            % Reset buttons
            app.Lamp5.Color = 'Red';
            app.Lamp6.Color = 'Red';
                
        end

        % Button pushed function: ExportDicomButton
        function ExportDicomButtonPushed(app, event)
            
            % If running, stop the movie
            StopButtonPushed(app, event);
            
            % Indicate busy
            app.Lamp5.Color = 'Yellow';
            app.SwitchOffAllButtons;
            
            % Ask for dicom export directory
            app.RetrospectiveUIFigure.Visible = 'off';
            app.dicom_export_path = uigetdir(app.dicom_export_path,'Dicom export path'); 
            app.RetrospectiveUIFigure.Visible = 'on';
            drawnow;
            
            % Check if directory exists
            ready = true;
            if ~ischar(app.dicom_export_path)     % user cancel
                ready = false;
                app.dicom_export_path = '';
            else
                [~, Attrib] = fileattrib(app.dicom_export_path);
                if ~any(Attrib.UserWrite)       % check if directory is writable
                    ready = false;
                end
            end
                        
            % Export to dicom
            if ready
                             
                app.TextMessage('DICOM export ...');
                
                app.TextMessage('Searching for DICOM information ...');
                
                % dcmpath.txt
                dcm_spath{1} = fileread('dcmpath.txt');
                dcm_spath{1} = [dcm_spath{1},extractAfter(dcm_spath{1},['Data',filesep])];
                
                % Assuming that MRD and Data directory share the same base-directory
                % base / MRD / 343 / 8455 / 
                % base / data / 343 / DICOM / 8455 / 
                % base / data / 343 / DICOM / 8455 / 1 /
                % base / data / 343 / DICOM / 8455 / 2 /
                dcm_spath{2} = replace(app.mrd_import_path,'MRD','Data');
                dcmpos = strfind(dcm_spath{2},filesep);
                dcmpos = dcmpos(end-1);
                dcm_spath{2} = insertBefore(dcm_spath{2},dcmpos,[filesep,'DICOM']);
                dcm_spath{3} = [dcm_spath{2},'1',filesep];
                dcm_spath{4} = [dcm_spath{2},'2',filesep];
            
                dcm_exist = true;
            
                if ~isempty(dir([dcm_spath{1},filesep,'*.dcm']))
                    
                    dcm_path = dcm_spath{1};
                    
                elseif ~isempty(dir([dcm_spath{2},filesep,'*.dcm']))
                    
                    dcm_path = dcm_spath{2};
                    
                elseif ~isempty(dir([dcm_spath{3},filesep,'*.dcm']))
                    
                    dcm_path = dcm_spath{3};
                    
                elseif ~isempty(dir([dcm_spath{4},filesep,'*.dcm']))
                    
                    dcm_path = dcm_spath{4};
                    
                else
                    
                    dcm_exist = false;
                    
                end
                
                
                % Export
                if dcm_exist
                    
                    % Export, reading dicom information from dcm_path
                    export_dicom_dcm(app,dcm_path,app.dicom_export_path,app.cs_movie,app.parameters,app.heartrate,app.acq_dur,app.nr_frames,app.tag);
         
                else
                    
                    % Export without image orienation information
                    app.TextMessage('WARNING: DICOM image orientation information not available ...');
                    export_dicom(app.dicom_export_path,app.cs_movie,app.parameters,app.heartrate,app.acq_dur,app.nr_frames,app.tag);
         
                end
                
                app.Lamp5.Color = 'Green';
                
            else
                
                f = app.RetrospectiveUIFigure;
                uialert(f,'Not a valid directory','Error');
                app.Lamp5.Color = 'Red';
                
            end
            
            % Reset buttons
            app.SetDashboardButtons(127);
            app.NavSelButtons(true);
            app.NavViewingButtons(true);
            app.CineRecoButtons(true);
            app.stopmovie = true;  
            app.MovieButtons(true);
            drawnow;
            
        end

        % Button pushed function: ExportGIFButton
        function ExportGIFButtonPushed(app, event)
            
            % If running, stop the movie
            StopButtonPushed(app, event);
            
            % Indicate busy
            app.Lamp6.Color = 'Yellow';
            app.SwitchOffAllButtons;
            
            % Ask for directory
            app.RetrospectiveUIFigure.Visible = 'off';
            app.gif_export_path = uigetdir(app.gif_export_path,'GIF export path'); 
            app.RetrospectiveUIFigure.Visible = 'on';
            drawnow;
            
            % Check if directory exists
            ready = true;
            if ~ischar(app.gif_export_path)   % user cancel
                ready = false;
                app.gif_export_path = '';
            else
                [~, Attrib] = fileattrib(app.gif_export_path);   
                if ~any(Attrib.UserWrite)   % check if directory is writable
                    ready = false;
                end
            end
            
            % Export to animated gif
            if ready
                app.TextMessage('Animated GIF export ...');
                export_gif(app.gif_export_path,app.movie,app.nr_frames,app.tag,app.MovieScaleSlider.Value);
                app.Lamp6.Color = 'Green';
            else
                f = app.RetrospectiveUIFigure;
                uialert(f,'Not a valid directory','Error');   
                app.Lamp6.Color = 'Red';
            end
                
            % Reset buttons
            app.SetDashboardButtons(127);
            app.NavSelButtons(true);
            app.NavViewingButtons(true);
            app.CineRecoButtons(true);
            app.stopmovie = true;  
            app.MovieButtons(true);
            drawnow;
                
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            
            % Remove subfolders from search path
            if (~isdeployed)
                info = dir(which('retrospective.mlapp'));
                app.appdir = info.folder;
                rmpath(genpath(app.appdir));
                cd(app.olddir);
            end
            
            % Exit
            app.delete;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RetrospectiveUIFigure = uifigure('Visible', 'off');
            app.DashboardPanel = uipanel(app.RetrospectiveUIFigure);
            app.DashboardPanel.Title = 'Dashboard';
            app.LoadMRDfileButton = uibutton(app.DashboardPanel, 'push');
            app.LoadMRDfileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadMRDfileButtonPushed, true);
            app.LoadMRDfileButton.Enable = 'off';
            app.LoadMRDfileButton.Text = 'Load MRD file';
            app.DetectedratesBPMPanel = uipanel(app.DashboardPanel);
            app.DetectedratesBPMPanel.Title = 'Heart / respiration rates';
            app.respbpmLabel = uilabel(app.DetectedratesBPMPanel);
            app.respbpmLabel.Text = 'resp. (bpm)';
            app.RespirationEditField = uieditfield(app.DetectedratesBPMPanel, 'numeric');
            app.RespirationEditField.Limits = [0 200];
            app.RespirationEditField.ValueChangedFcn = createCallbackFcn(app, @FilterEditFieldValueChanged, true);
            app.RespirationEditField.Enable = 'off';
            app.RespirationEditField.Value = 50;
            app.heartbpmLabel = uilabel(app.DetectedratesBPMPanel);
            app.heartbpmLabel.Text = 'heart (bpm)';
            app.HeartEditField = uieditfield(app.DetectedratesBPMPanel, 'numeric');
            app.HeartEditField.Limits = [0 2000];
            app.HeartEditField.ValueChangedFcn = createCallbackFcn(app, @FilterEditFieldValueChanged, true);
            app.HeartEditField.Enable = 'off';
            app.HeartEditField.Value = 500;
            app.windowLabel = uilabel(app.DetectedratesBPMPanel);
            app.windowLabel.Text = 'window (%)';
            app.RespirationWindowEditField = uieditfield(app.DetectedratesBPMPanel, 'numeric');
            app.RespirationWindowEditField.Limits = [5 95];
            app.RespirationWindowEditField.ValueDisplayFormat = '%.0f';
            app.RespirationWindowEditField.ValueChangedFcn = createCallbackFcn(app, @FilterEditFieldValueChanged, true);
            app.RespirationWindowEditField.Enable = 'off';
            app.RespirationWindowEditField.Value = 30;
            app.RespirationWidthEditField = uieditfield(app.DetectedratesBPMPanel, 'numeric');
            app.RespirationWidthEditField.Limits = [5 100];
            app.RespirationWidthEditField.ValueChangedFcn = createCallbackFcn(app, @FilterEditFieldValueChanged, true);
            app.RespirationWidthEditField.Enable = 'off';
            app.RespirationWidthEditField.Value = 20;
            app.HeartWidthEditField = uieditfield(app.DetectedratesBPMPanel, 'numeric');
            app.HeartWidthEditField.Limits = [20 300];
            app.HeartWidthEditField.ValueDisplayFormat = '%.0f';
            app.HeartWidthEditField.ValueChangedFcn = createCallbackFcn(app, @FilterEditFieldValueChanged, true);
            app.HeartWidthEditField.Enable = 'off';
            app.HeartWidthEditField.Value = 100;
            app.centerLabel = uilabel(app.DetectedratesBPMPanel);
            app.centerLabel.Text = 'center';
            app.widthLabel = uilabel(app.DetectedratesBPMPanel);
            app.widthLabel.Text = 'width';
            app.ResetFilterValuesButton = uibutton(app.DetectedratesBPMPanel, 'push');
            app.ResetFilterValuesButton.ButtonPushedFcn = createCallbackFcn(app, @ResetFilterValuesButtonPushed, true);
            app.ResetFilterValuesButton.Enable = 'off';
            app.ResetFilterValuesButton.Text = 'reset';
            app.Lamp1 = uilamp(app.DashboardPanel);
            app.FilterNavigatorButton = uibutton(app.DashboardPanel, 'push');
            app.FilterNavigatorButton.ButtonPushedFcn = createCallbackFcn(app, @FilterNavigatorButtonPushed, true);
            app.FilterNavigatorButton.Enable = 'off';
            app.FilterNavigatorButton.Text = 'Filter navigator';
            app.Lamp2 = uilamp(app.DashboardPanel);
            app.SortKspaceButton = uibutton(app.DashboardPanel, 'push');
            app.SortKspaceButton.ButtonPushedFcn = createCallbackFcn(app, @SortKspaceButtonPushed, true);
            app.SortKspaceButton.Enable = 'off';
            app.SortKspaceButton.Text = 'Sort k-space';
            app.Lamp3 = uilamp(app.DashboardPanel);
            app.ReconstructButton = uibutton(app.DashboardPanel, 'push');
            app.ReconstructButton.ButtonPushedFcn = createCallbackFcn(app, @ReconstructButtonPushed, true);
            app.ReconstructButton.Enable = 'off';
            app.ReconstructButton.Text = 'Reconstruct';
            app.Lamp4 = uilamp(app.DashboardPanel);
            app.CSrecoparametersPanel = uipanel(app.DashboardPanel);
            app.CSrecoparametersPanel.Title = 'CS reco parameters';
            app.TVtimeEditFieldLabel = uilabel(app.CSrecoparametersPanel);
            app.TVtimeEditFieldLabel.Text = 'TVtime';
            app.TVtimeEditField = uieditfield(app.CSrecoparametersPanel, 'numeric');
            app.TVtimeEditField.Limits = [0 2];
            app.TVtimeEditField.ValueDisplayFormat = '%.4f';
            app.TVtimeEditField.ValueChangedFcn = createCallbackFcn(app, @RecoParEditFieldValueChanged, true);
            app.TVtimeEditField.Enable = 'off';
            app.TVtimeEditField.Value = 0.1;
            app.WVxyEditFieldLabel = uilabel(app.CSrecoparametersPanel);
            app.WVxyEditFieldLabel.Text = 'WVxy';
            app.WVxyEditField = uieditfield(app.CSrecoparametersPanel, 'numeric');
            app.WVxyEditField.Limits = [0 1];
            app.WVxyEditField.ValueDisplayFormat = '%.4f';
            app.WVxyEditField.ValueChangedFcn = createCallbackFcn(app, @RecoParEditFieldValueChanged, true);
            app.WVxyEditField.Enable = 'off';
            app.WVxyEditField.Value = 0.001;
            app.RecoLabel = uilabel(app.CSrecoparametersPanel);
            app.RecoLabel.Text = 'Reco';
            app.RecoEditField = uieditfield(app.CSrecoparametersPanel, 'text');
            app.RecoEditField.Editable = 'off';
            app.RecoEditField.Value = 'Matlab';
            app.ProgressGauge = uigauge(app.CSrecoparametersPanel, 'linear');
            app.ProgressGauge.MajorTicks = [0 25 50 75 100];
            app.TVxyEditFieldLabel = uilabel(app.CSrecoparametersPanel);
            app.TVxyEditFieldLabel.Text = 'TVxy';
            app.TVxyEditField = uieditfield(app.CSrecoparametersPanel, 'numeric');
            app.TVxyEditField.Limits = [0 2];
            app.TVxyEditField.ValueDisplayFormat = '%.4f';
            app.TVxyEditField.ValueChangedFcn = createCallbackFcn(app, @RecoParEditFieldValueChanged, true);
            app.TVxyEditField.Enable = 'off';
            app.VNSACheckBox = uicheckbox(app.CSrecoparametersPanel);
            app.VNSACheckBox.ValueChangedFcn = createCallbackFcn(app, @RecoParEditFieldValueChanged, true);
            app.VNSACheckBox.Enable = 'off';
            app.VNSACheckBox.Text = '';
            app.VNSALabel = uilabel(app.CSrecoparametersPanel);
            app.VNSALabel.Text = 'VNSA';
            app.ExportDicomButton = uibutton(app.DashboardPanel, 'push');
            app.ExportDicomButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDicomButtonPushed, true);
            app.ExportDicomButton.Enable = 'off';
            app.ExportDicomButton.Text = 'Export Dicom';
            app.Lamp5 = uilamp(app.DashboardPanel);
            app.CinePanel = uipanel(app.DashboardPanel);
            app.CinePanel.Title = 'Cine';
            app.framesEditFieldLabel = uilabel(app.CinePanel);
            app.framesEditFieldLabel.Text = '# frames';
            app.FramesEditField = uieditfield(app.CinePanel, 'numeric');
            app.FramesEditField.Limits = [6 240];
            app.FramesEditField.RoundFractionalValues = 'on';
            app.FramesEditField.ValueDisplayFormat = '%.0f';
            app.FramesEditField.ValueChangedFcn = createCallbackFcn(app, @FramesEditFieldValueChanged, true);
            app.FramesEditField.Enable = 'off';
            app.FramesEditField.Value = 25;
            app.movieDropDownLabel = uilabel(app.CinePanel);
            app.movieDropDownLabel.Text = 'movie';
            app.CardiacRespDropDown = uidropdown(app.CinePanel);
            app.CardiacRespDropDown.Items = {'cardiac', 'respiratory'};
            app.CardiacRespDropDown.ValueChangedFcn = createCallbackFcn(app, @FramesEditFieldValueChanged, true);
            app.CardiacRespDropDown.Enable = 'off';
            app.CardiacRespDropDown.Value = 'cardiac';
            app.ExitButton = uibutton(app.DashboardPanel, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.ExportGIFButton = uibutton(app.DashboardPanel, 'push');
            app.ExportGIFButton.ButtonPushedFcn = createCallbackFcn(app, @ExportGIFButtonPushed, true);
            app.ExportGIFButton.Enable = 'off';
            app.ExportGIFButton.Text = 'Export GIF';
            app.Lamp6 = uilamp(app.DashboardPanel);
            app.SelfgatedCardiacMRIReconstructorLabel = uilabel(app.RetrospectiveUIFigure);
            app.SelfgatedCardiacMRIReconstructorLabel.Text = 'Selfgated Cardiac MRI Reconstructor';
            app.AcquisitionparametersPanel = uipanel(app.RetrospectiveUIFigure);
            app.AcquisitionparametersPanel.Title = 'Acquisition parameters';
            app.NyEditFieldLabel = uilabel(app.AcquisitionparametersPanel);
            app.NyEditFieldLabel.Text = 'Ny';
            app.NyViewField = uieditfield(app.AcquisitionparametersPanel, 'numeric');
            app.NyViewField.Editable = 'off';
            app.NxEditFieldLabel = uilabel(app.AcquisitionparametersPanel);
            app.NxEditFieldLabel.Text = 'Nx';
            app.NxViewField = uieditfield(app.AcquisitionparametersPanel, 'numeric');
            app.NxViewField.Editable = 'off';
            app.klinesrepetitionLabel = uilabel(app.AcquisitionparametersPanel);
            app.klinesrepetitionLabel.Text = 'k-lines / repetition';
            app.KlinesRepetitionViewField = uieditfield(app.AcquisitionparametersPanel, 'numeric');
            app.KlinesRepetitionViewField.Editable = 'off';
            app.repetitionsLabel = uilabel(app.AcquisitionparametersPanel);
            app.repetitionsLabel.Text = 'repetitions';
            app.RepetitionsViewField = uieditfield(app.AcquisitionparametersPanel, 'numeric');
            app.RepetitionsViewField.RoundFractionalValues = 'on';
            app.RepetitionsViewField.ValueDisplayFormat = '%.0f';
            app.RepetitionsViewField.Editable = 'off';
            app.klinesLabel = uilabel(app.AcquisitionparametersPanel);
            app.klinesLabel.Text = ' # k-lines';
            app.NrKlinesViewField = uieditfield(app.AcquisitionparametersPanel, 'numeric');
            app.NrKlinesViewField.ValueDisplayFormat = '%.0f';
            app.NrKlinesViewField.Editable = 'off';
            app.scantimeminLabel = uilabel(app.AcquisitionparametersPanel);
            app.scantimeminLabel.Text = 'scan time (min)';
            app.AcquisitionTimeMinViewField = uieditfield(app.AcquisitionparametersPanel, 'numeric');
            app.AcquisitionTimeMinViewField.Editable = 'off';
            app.DataFile = uitextarea(app.AcquisitionparametersPanel);
            app.DataFile.ValueChangedFcn = createCallbackFcn(app, @LoadMRDfileButtonPushed, true);
            app.DataFile.Editable = 'off';
            app.DataFile.Value = {' '};
            app.datafileLabel = uilabel(app.AcquisitionparametersPanel);
            app.datafileLabel.Text = 'data file';
            app.averagesEditFieldLabel = uilabel(app.AcquisitionparametersPanel);
            app.averagesEditFieldLabel.Text = 'averages';
            app.AveragesViewField = uieditfield(app.AcquisitionparametersPanel, 'numeric');
            app.AveragesViewField.Editable = 'off';
            app.fillingLabel = uilabel(app.AcquisitionparametersPanel);
            app.fillingLabel.Text = 'filling (%)';
            app.FillingViewField = uieditfield(app.AcquisitionparametersPanel, 'numeric');
            app.FillingViewField.Limits = [0 100];
            app.FillingViewField.ValueDisplayFormat = '%.0f';
            app.FillingViewField.Editable = 'off';
            app.TRmsLabel = uilabel(app.AcquisitionparametersPanel);
            app.TRmsLabel.Text = 'TR (ms)';
            app.TRViewField = uieditfield(app.AcquisitionparametersPanel, 'numeric');
            app.TRViewField.Editable = 'off';
            app.trajectoryEditFieldLabel = uilabel(app.AcquisitionparametersPanel);
            app.trajectoryEditFieldLabel.Text = 'trajectory';
            app.TrajectoryViewField = uieditfield(app.AcquisitionparametersPanel, 'text');
            app.TrajectoryViewField.Editable = 'off';
            app.TrajectoryFig = uiaxes(app.AcquisitionparametersPanel);
            title(app.TrajectoryFig, '')
            xlabel(app.TrajectoryFig, '')
            ylabel(app.TrajectoryFig, '')
            app.TrajectoryFig.XTick = [];
            app.TrajectoryFig.YTick = [];
            app.GustavStrijkersAmsterdamUMCVersion36Label = uilabel(app.RetrospectiveUIFigure);
            app.GustavStrijkersAmsterdamUMCVersion36Label.Text = 'Gustav Strijkers, Amsterdam UMC, Version 3.6';
            app.NavigatorPanel = uipanel(app.RetrospectiveUIFigure);
            app.NavigatorPanel.Title = 'Navigator';
            app.RawNavFig = uiaxes(app.NavigatorPanel);
            title(app.RawNavFig, 'Raw navigator')
            xlabel(app.RawNavFig, 'Samples')
            ylabel(app.RawNavFig, 'Signal')
            app.SpectrumFig = uiaxes(app.NavigatorPanel);
            title(app.SpectrumFig, 'Navigator Spectrum')
            xlabel(app.SpectrumFig, 'Frequency (bpm)')
            ylabel(app.SpectrumFig, 'Intensity')
            app.primaryLabel = uilabel(app.NavigatorPanel);
            app.primaryLabel.Text = 'primary';
            app.NavigatorEndEditField = uieditfield(app.NavigatorPanel, 'numeric');
            app.NavigatorEndEditField.Limits = [1 25];
            app.NavigatorEndEditField.ValueDisplayFormat = '%.0f';
            app.NavigatorEndEditField.ValueChangedFcn = createCallbackFcn(app, @NavPointEditFieldValueChanged, true);
            app.NavigatorEndEditField.Enable = 'off';
            app.NavigatorEndEditField.Value = 10;
            app.pointsLabel = uilabel(app.NavigatorPanel);
            app.pointsLabel.Text = '# points';
            app.NavigatorNRPointsEditField = uieditfield(app.NavigatorPanel, 'numeric');
            app.NavigatorNRPointsEditField.Limits = [1 8];
            app.NavigatorNRPointsEditField.ValueDisplayFormat = '%.0f';
            app.NavigatorNRPointsEditField.ValueChangedFcn = createCallbackFcn(app, @NavPointEditFieldValueChanged, true);
            app.NavigatorNRPointsEditField.Enable = 'off';
            app.NavigatorNRPointsEditField.Value = 1;
            app.FlipSwitchLabel = uilabel(app.NavigatorPanel);
            app.FlipSwitchLabel.Text = 'Flip';
            app.NavigatorFlipSwitch = uiswitch(app.NavigatorPanel, 'toggle');
            app.NavigatorFlipSwitch.Items = {'Down', 'Up'};
            app.NavigatorFlipSwitch.ValueChangedFcn = createCallbackFcn(app, @NavPointEditFieldValueChanged, true);
            app.NavigatorFlipSwitch.Enable = 'off';
            app.NavigatorFlipSwitch.Value = 'Up';
            app.NavigatorLabel = uilabel(app.NavigatorPanel);
            app.NavigatorLabel.Text = 'Navigator';
            app.NavigatoranalysisPanel = uipanel(app.RetrospectiveUIFigure);
            app.NavigatoranalysisPanel.Title = 'Navigator analysis';
            app.CardiacNavFig = uiaxes(app.NavigatoranalysisPanel);
            title(app.CardiacNavFig, 'Heart navigator')
            xlabel(app.CardiacNavFig, 'Samples')
            ylabel(app.CardiacNavFig, 'Signal')
            app.RespirationNavFig = uiaxes(app.NavigatoranalysisPanel);
            title(app.RespirationNavFig, 'Respiration navigator')
            xlabel(app.RespirationNavFig, 'Samples')
            ylabel(app.RespirationNavFig, 'Signal')
            app.HeartRateTimeFig = uiaxes(app.NavigatoranalysisPanel);
            title(app.HeartRateTimeFig, 'Heart rate')
            xlabel(app.HeartRateTimeFig, 'Time (s)')
            ylabel(app.HeartRateTimeFig, 'Rate (bpm)')
            app.HeartRateTimeFig.XLim = [0 100];
            app.HeartRateTimeFig.YLim = [0 1000];
            app.RespirationRateTimeFig = uiaxes(app.NavigatoranalysisPanel);
            title(app.RespirationRateTimeFig, 'Respiration rate')
            xlabel(app.RespirationRateTimeFig, 'Time (s)')
            ylabel(app.RespirationRateTimeFig, 'Rate (bpm)')
            app.RespirationRateTimeFig.XLim = [0 100];
            app.RespirationRateTimeFig.YLim = [0 100];
            app.startEditFieldLabel = uilabel(app.NavigatoranalysisPanel);
            app.startEditFieldLabel.Text = 'start';
            app.StartEditField = uieditfield(app.NavigatoranalysisPanel, 'numeric');
            app.StartEditField.Limits = [1 Inf];
            app.StartEditField.RoundFractionalValues = 'on';
            app.StartEditField.ValueDisplayFormat = '%.0f';
            app.StartEditField.ValueChangedFcn = createCallbackFcn(app, @DisplayEditFieldValuesChanged, true);
            app.StartEditField.Enable = 'off';
            app.StartEditField.Value = 1000;
            app.rangeEditFieldLabel = uilabel(app.NavigatoranalysisPanel);
            app.rangeEditFieldLabel.Text = 'range';
            app.RangeEditField = uieditfield(app.NavigatoranalysisPanel, 'numeric');
            app.RangeEditField.Limits = [1 2000];
            app.RangeEditField.RoundFractionalValues = 'on';
            app.RangeEditField.ValueDisplayFormat = '%.0f';
            app.RangeEditField.ValueChangedFcn = createCallbackFcn(app, @DisplayEditFieldValuesChanged, true);
            app.RangeEditField.Enable = 'off';
            app.RangeEditField.Value = 600;
            app.DisplayrangeLabel = uilabel(app.NavigatoranalysisPanel);
            app.DisplayrangeLabel.Text = 'Display range';
            app.StartSlider = uislider(app.NavigatoranalysisPanel);
            app.StartSlider.Limits = [1 6000];
            app.StartSlider.MajorTicks = [];
            app.StartSlider.MajorTickLabels = {'1', '6000'};
            app.StartSlider.ValueChangedFcn = createCallbackFcn(app, @DisplaySlidersValueChanged, true);
            app.StartSlider.MinorTicks = [];
            app.StartSlider.Enable = 'off';
            app.StartSlider.Value = 1000;
            app.RangeSlider = uislider(app.NavigatoranalysisPanel);
            app.RangeSlider.Limits = [1 2000];
            app.RangeSlider.MajorTicks = [];
            app.RangeSlider.MajorTickLabels = {'1', '6000'};
            app.RangeSlider.ValueChangedFcn = createCallbackFcn(app, @DisplaySlidersValueChanged, true);
            app.RangeSlider.MinorTicks = [];
            app.RangeSlider.Enable = 'off';
            app.RangeSlider.Value = 600;
            app.WindowCheckBox = uicheckbox(app.NavigatoranalysisPanel);
            app.WindowCheckBox.ValueChangedFcn = createCallbackFcn(app, @WindowCheckBoxValueChanged, true);
            app.WindowCheckBox.Enable = 'off';
            app.WindowCheckBox.Text = '  Window';
            app.startEditFieldLabel_2 = uilabel(app.NavigatoranalysisPanel);
            app.startEditFieldLabel_2.Text = 'start';
            app.WindowStartEditField = uieditfield(app.NavigatoranalysisPanel, 'numeric');
            app.WindowStartEditField.Limits = [0 Inf];
            app.WindowStartEditField.RoundFractionalValues = 'on';
            app.WindowStartEditField.ValueDisplayFormat = '%.0f';
            app.WindowStartEditField.ValueChangedFcn = createCallbackFcn(app, @WindowEditFieldValueChanged, true);
            app.WindowStartEditField.Enable = 'off';
            app.endLabel = uilabel(app.NavigatoranalysisPanel);
            app.endLabel.Text = 'end';
            app.WindowEndEditField = uieditfield(app.NavigatoranalysisPanel, 'numeric');
            app.WindowEndEditField.Limits = [1 Inf];
            app.WindowEndEditField.RoundFractionalValues = 'on';
            app.WindowEndEditField.ValueDisplayFormat = '%.0f';
            app.WindowEndEditField.ValueChangedFcn = createCallbackFcn(app, @WindowEditFieldValueChanged, true);
            app.WindowEndEditField.Enable = 'off';
            app.WindowEndEditField.Value = 1;
            app.WindowStartSlider = uislider(app.NavigatoranalysisPanel);
            app.WindowStartSlider.Limits = [1 6000];
            app.WindowStartSlider.MajorTicks = [];
            app.WindowStartSlider.MajorTickLabels = {'1', '6000'};
            app.WindowStartSlider.ValueChangedFcn = createCallbackFcn(app, @WindowSliderValueChanged, true);
            app.WindowStartSlider.MinorTicks = [];
            app.WindowStartSlider.Enable = 'off';
            app.WindowStartSlider.Value = 1000;
            app.WindowEndSlider = uislider(app.NavigatoranalysisPanel);
            app.WindowEndSlider.Limits = [1 6000];
            app.WindowEndSlider.MajorTicks = [];
            app.WindowEndSlider.MajorTickLabels = {'1', '6000'};
            app.WindowEndSlider.ValueChangedFcn = createCallbackFcn(app, @WindowSliderValueChanged, true);
            app.WindowEndSlider.MinorTicks = [];
            app.WindowEndSlider.Enable = 'off';
            app.WindowEndSlider.Value = 1000;
            app.MoviePanel = uipanel(app.RetrospectiveUIFigure);
            app.MoviePanel.Title = 'Movie';
            app.PlayButton = uibutton(app.MoviePanel, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Enable = 'off';
            app.PlayButton.Text = 'Play';
            app.StopButton = uibutton(app.MoviePanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Enable = 'off';
            app.StopButton.Text = 'Stop';
            app.frameLabel = uilabel(app.MoviePanel);
            app.frameLabel.Text = 'frame #';
            app.MovieFrameEditField = uieditfield(app.MoviePanel, 'numeric');
            app.MovieFrameEditField.ValueChangedFcn = createCallbackFcn(app, @MovieFrameEditFieldValueChanged, true);
            app.MovieFrameEditField.Enable = 'off';
            app.intensityscalingLabel = uilabel(app.MoviePanel);
            app.intensityscalingLabel.Text = {'intensity'; 'scaling'};
            app.MovieScaleSlider = uislider(app.MoviePanel);
            app.MovieScaleSlider.Limits = [0 2];
            app.MovieScaleSlider.MajorTicks = [0 1 2];
            app.MovieScaleSlider.ValueChangedFcn = createCallbackFcn(app, @MovieScaleSliderValueChanged, true);
            app.MovieScaleSlider.Enable = 'off';
            app.MovieScaleSlider.Value = 1;
            app.ccButton = uibutton(app.MoviePanel, 'push');
            app.ccButton.ButtonPushedFcn = createCallbackFcn(app, @ccButtonPushed, true);
            app.ccButton.Enable = 'off';
            app.ccButton.Text = 'cc';
            app.ccwButton = uibutton(app.MoviePanel, 'push');
            app.ccwButton.ButtonPushedFcn = createCallbackFcn(app, @ccwButtonPushed, true);
            app.ccwButton.Enable = 'off';
            app.ccwButton.Text = {'ccw'; ''};
            app.RotateLabel = uilabel(app.MoviePanel);
            app.RotateLabel.Text = 'Rotate';
            app.FrameIncrButton = uibutton(app.MoviePanel, 'push');
            app.FrameIncrButton.ButtonPushedFcn = createCallbackFcn(app, @FrameIncrButtonPushed, true);
            app.FrameIncrButton.Enable = 'off';
            app.FrameIncrButton.Text = '+1';
            app.FrameLabel = uilabel(app.MoviePanel);
            app.FrameLabel.Text = 'Frame';
            app.FrameDecrButton = uibutton(app.MoviePanel, 'push');
            app.FrameDecrButton.ButtonPushedFcn = createCallbackFcn(app, @FrameDecrButtonPushed, true);
            app.FrameDecrButton.Enable = 'off';
            app.FrameDecrButton.Text = '-1';
            app.heartbpmLabel_2 = uilabel(app.MoviePanel);
            app.heartbpmLabel_2.Text = 'heart (bpm)';
            app.FinalHeartViewField = uieditfield(app.MoviePanel, 'numeric');
            app.FinalHeartViewField.Editable = 'off';
            app.FinalHeartViewField.Enable = 'off';
            app.respbpmLabel_2 = uilabel(app.MoviePanel);
            app.respbpmLabel_2.Text = 'resp. (bpm)';
            app.FinalRespirationViewField = uieditfield(app.MoviePanel, 'numeric');
            app.FinalRespirationViewField.Editable = 'off';
            app.FinalRespirationViewField.Enable = 'off';
            app.MovieFig = uiaxes(app.MoviePanel);
            app.MovieFig.XLim = [0 255];
            app.MovieFig.YLim = [0 255];
            app.MovieFig.XTick = [];
            app.MovieFig.YTick = [];
            app.Image3 = uiimage(app.RetrospectiveUIFigure);
            app.Image3.ImageSource = 'AmsterdamUMC.gif';
            app.MessageBox = uitextarea(app.RetrospectiveUIFigure);
            app.MessageBox.Value = {'Messages ....'};
            % Show the figure after all components are created
            app.RetrospectiveUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = retrospective

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RetrospectiveUIFigure)

            % Execute the startup function
            runStartupFcn(app, @StartupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RetrospectiveUIFigure)
        end
    end
end